classdef MICA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MICAProjectUIFigure            matlab.ui.Figure
        LoadButton                     matlab.ui.control.Button
        SpectrogramButton              matlab.ui.control.Button
        TemporalviewButton             matlab.ui.control.Button
        OverlapSpinnerLabel            matlab.ui.control.Label
        OverlapSpinner                 matlab.ui.control.Spinner
        NumberoftimeunitsSpinnerLabel  matlab.ui.control.Label
        NumberoftimeunitsSpinner       matlab.ui.control.Spinner
        F_maxHzSpinnerLabel            matlab.ui.control.Label
        F_maxHzSpinner                 matlab.ui.control.Spinner
        PQRSTButton                    matlab.ui.control.Button
        HeartrateButton                matlab.ui.control.Button
        AnalysisLabel                  matlab.ui.control.Label
        TimeunitDropDownLabel          matlab.ui.control.Label
        TimeunitDropDown               matlab.ui.control.DropDown
        TestsLabel                     matlab.ui.control.Label
        EctopicButton                  matlab.ui.control.Button
        AtrialButton                   matlab.ui.control.Button
        GeneralLabel                   matlab.ui.control.Label
        WindowsecSpinnerLabel          matlab.ui.control.Label
        WindowsecSpinner               matlab.ui.control.Spinner
        WindowsecSpinner_2Label        matlab.ui.control.Label
        WindowsecSpinner_2             matlab.ui.control.Spinner
        QRStimemsEditFieldLabel        matlab.ui.control.Label
        QRStimemsEditField             matlab.ui.control.NumericEditField
        NfftSpinnerLabel               matlab.ui.control.Label
        NfftSpinner                    matlab.ui.control.Spinner
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        name            % patient name
        ecg             % electrocardiogram
        Fs              % sampling frequency
        N=17            % QRS width
        Ntime=1         % number of seconds or minutes to display
        Nfft=1024       % number of point for FFT
        time_unit="sec" % abscissas unit
        Fmax=3          % Max frequency for spectrogram
        overlap=50
        window=30       % time window in second
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load datas
            [file, path] = uigetfile("*.mat");
            
            if isnumeric(file)      % user cancels the operation
                return
            end
            
            patient = load(path+""+file);
            app.ecg = patient.ecg;
            app.Fs = patient.Fs;
            app.name=split(file, ".");app.name=strrep(app.name(1), "_", " ");

            
            % ecg polarity
            if max(app.ecg(1:4*app.Fs)) < -min(app.ecg(1:4*app.Fs))
                app.ecg = -app.ecg;
            end
            
            % Display
            cla(app.UIAxes);
            time=((0:1:length(app.ecg)-1)/app.Fs)/(1 + 59*strcmp(app.time_unit, "min"));
            plot(app.UIAxes, time, app.ecg);
            %set(colorbar(app.UIAxes), "visible", "off");
            xlim(app.UIAxes, [0, time(length(app.ecg)-1)]);
            ylim(app.UIAxes, [min(app.ecg), max(app.ecg)]);
            xlabel(app.UIAxes, "Time ("+app.time_unit+")");
            ylabel(app.UIAxes, "Tension (mV)");
            title(app.UIAxes, app.name);
        end

        % Button pushed function: SpectrogramButton
        function SpectrogramButtonPushed(app, event)
            [X, f, t] = spectrogram(app.ecg, hamming(app.Nfft), floor(app.Nfft*app.overlap/100), app.Nfft, app.Fs);
            t=t/(1 + 59*strcmp(app.time_unit, "min"));
            cla(app.UIAxes);
            imagesc(app.UIAxes, t, f, 20*log(abs(X)));
            xlim(app.UIAxes, [t(1), t(length(t))]);
            ylim(app.UIAxes, [0, app.Fmax]);
            xlabel(app.UIAxes, "Time ("+app.time_unit+")");
            ylabel(app.UIAxes, "Frequency (Hz)");
            %colorbar(app.UIAxes);
            title(app.UIAxes, app.name+" spectrogram");
        end

        % Value changed function: OverlapSpinner
        function OverlapSpinnerValueChanged(app, event)
            app.overlap = app.OverlapSpinner.Value;
        end

        % Value changed function: NumberoftimeunitsSpinner
        function NumberoftimeunitsSpinnerValueChanged(app, event)
            app.Ntime = app.NumberoftimeunitsSpinner.Value;
        end

        % Button pushed function: TemporalviewButton
        function TemporalviewButtonPushed(app, event)
            time=(0:1:length(app.ecg)-1)/app.Fs/(1 + 59*strcmp(app.time_unit, "min"));
            cla(app.UIAxes);
            plot(app.UIAxes, time, app.ecg);
            xlim(app.UIAxes, [0, app.Ntime]);
            ylim(app.UIAxes, [min(app.ecg), max(app.ecg)]);
            %set(colorbar(app.UIAxes), "visible", "off");
            legend(app.UIAxes, "ecg");
            xlabel(app.UIAxes, "Time ("+app.time_unit+")");
            ylabel(app.UIAxes, "Tension (mV)");
            title(app.UIAxes, app.name);
        end

        % Value changed function: F_maxHzSpinner
        function F_maxHzSpinnerValueChanged(app, event)
            app.Fmax = app.F_maxHzSpinner.Value;
        end

        % Button pushed function: PQRSTButton
        function PQRSTButtonPushed(app, event)
            % computing PQRST positions
            pos_QRS = QRS(app.ecg, app.Fs, app.N);
            RR_lengths = RR_length(app.ecg, pos_QRS);
            PQRST = PT(app.ecg, RR_lengths, pos_QRS);
            % Display
            PQRST(PQRST==0)=NaN;
            time=(0:1:length(app.ecg)-1)/app.Fs/(1 + 59*strcmp(app.time_unit, "min"));
            cla(app.UIAxes);
            plot(app.UIAxes, time, app.ecg/max(abs(app.ecg)));
            colors = ["c", "g", "r", "k", "m"];
            hold(app.UIAxes, "on");
            for i= 1:5
                plot(app.UIAxes, time, PQRST(i, :)/max(abs(app.ecg)), strcat("s",colors(i)), "MarkerSize", 12, "MarkerEdgeColor", colors(i));
            end
            hold(app.UIAxes, "off");
            xlabel(app.UIAxes, "Time ("+app.time_unit+")");
            ylabel(app.UIAxes, "Electrical potential (normalized)");
            xlim(app.UIAxes, [0, app.Ntime]);
            ylim(app.UIAxes, [min(app.ecg)/max(app.ecg), 1]);
            title(app.UIAxes, app.name, "interpreter", "none");
            legend(app.UIAxes, "ecg", "P points" , "Q points", "R points", "S points", "T points");
        end

        % Button pushed function: HeartrateButton
        function HeartrateButtonPushed(app, event)
            addpath("src/Analyse/");
            
            %% Compute Heart rate
            [bpm, t] = heart_bpm(app.ecg, app.N, app.window, floor(app.overlap*app.window/100), app.Fs, app.time_unit);
            
            %% Display
            cla(app.UIAxes);
            plot(app.UIAxes, t, bpm, "s");
            xlim(app.UIAxes, [0, t(length(t))]);
            ylim(app.UIAxes, [min(bpm)-2, max(bpm)+2]);
            xlabel(app.UIAxes, "Time ("+app.time_unit+")");
            ylabel(app.UIAxes, "Frequency (bpm)");
            legend(app.UIAxes, "Heart rate")
            title(app.UIAxes, app.name + " Heart rate");
        end

        % Value changed function: TimeunitDropDown
        function TimeunitDropDownValueChanged(app, event)
            app.time_unit = app.TimeunitDropDown.Value;
        end

        % Button pushed function: AtrialButton
        function AtrialButtonPushed(app, event)
            %% Detection
            [Atrial_res, t] = Atrial_test(app.ecg, app.N, app.window, app.Fs, app.time_unit);
            
            %% Display
            
            Atrial_positive = 1*(Atrial_res == 1);
            Atrial_positive( Atrial_positive == 0) = NaN;
            Atrial_negative = 1*(Atrial_res == 1);
            Atrial_negative( Atrial_negative == 1) = NaN;
            
            stem(app.UIAxes, t, Atrial_positive, "sr");
            hold(app.UIAxes, "on");
            plot(app.UIAxes, t, Atrial_negative, "sg");
            hold(app.UIAxes, "off");
            legend(app.UIAxes, "Atrial positive", "Atrial negative");
            xlim(app.UIAxes, [0, t(length(t))]);
            ylim(app.UIAxes, [0, 1.2]);
            ylabel(app.UIAxes, "Boolean");
            xlabel(app.UIAxes, "time ("+app.time_unit+")");
            title(app.UIAxes, "Atrial test on "+app.name);
        end

        % Value changed function: WindowsecSpinner
        function WindowsecSpinnerValueChanged(app, event)
            app.window = app.WindowsecSpinner.Value;
        end

        % Value changed function: WindowsecSpinner_2
        function WindowsecSpinner_2ValueChanged(app, event)
            app.window = app.WindowsecSpinner_2.Value;
        end

        % Button pushed function: EctopicButton
        function EctopicButtonPushed(app, event)
            pos = QRS(app.ecg, app.Fs, app.N);
            RRs = RR_length(app.ecg, pos);
            RRs = RRs(2:length(RRs));
            
            RR_diff = diff(RRs);
            threshold = mean(RR_diff) + 4*sqrt(var(RR_diff));
            
            plot(app.UIAxes, RR_diff);
            yline(app.UIAxes, threshold);
            yline(app.UIAxes, -threshold);
            
            xlabel(app.UIAxes, "RR indexes");
            ylabel(app.UIAxes, "Time (sec)");
            legend(app.UIAxes, "RR interval", "Threshold");
            xlim(app.UIAxes, [0, length(RR_diff)]);
            ylim(app.UIAxes, [-1.1, 1.1]*threshold);
        end

        % Value changed function: QRStimemsEditField
        function QRStimemsEditFieldValueChanged(app, event)
            QRS_time = app.QRStimemsEditField.Value;
            app.N = floor(QRS_time/1000*app.Fs);
        end

        % Value changed function: NfftSpinner
        function NfftSpinnerValueChanged(app, event)
            app.Nfft = app.NfftSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MICAProjectUIFigure = uifigure('Visible', 'off');
            app.MICAProjectUIFigure.Name = 'MICA Project';
            app.LoadButton = uibutton(app.MICAProjectUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SpectrogramButton = uibutton(app.MICAProjectUIFigure, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramButtonPushed, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.TemporalviewButton = uibutton(app.MICAProjectUIFigure, 'push');
            app.TemporalviewButton.ButtonPushedFcn = createCallbackFcn(app, @TemporalviewButtonPushed, true);
            app.TemporalviewButton.Text = 'Temporal view';
            app.OverlapSpinnerLabel = uilabel(app.MICAProjectUIFigure);
            app.OverlapSpinnerLabel.Text = 'Overlap (%)';
            app.OverlapSpinner = uispinner(app.MICAProjectUIFigure);
            app.OverlapSpinner.Limits = [0 95];
            app.OverlapSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlapSpinnerValueChanged, true);
            app.OverlapSpinner.Value = 50;
            app.NumberoftimeunitsSpinnerLabel = uilabel(app.MICAProjectUIFigure);
            app.NumberoftimeunitsSpinnerLabel.Text = 'Number of time units';
            app.NumberoftimeunitsSpinner = uispinner(app.MICAProjectUIFigure);
            app.NumberoftimeunitsSpinner.Step = 0.5;
            app.NumberoftimeunitsSpinner.Limits = [0.5 10];
            app.NumberoftimeunitsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberoftimeunitsSpinnerValueChanged, true);
            app.NumberoftimeunitsSpinner.Value = 1;
            app.F_maxHzSpinnerLabel = uilabel(app.MICAProjectUIFigure);
            app.F_maxHzSpinnerLabel.Text = 'F_max (Hz)';
            app.F_maxHzSpinner = uispinner(app.MICAProjectUIFigure);
            app.F_maxHzSpinner.Limits = [1 10];
            app.F_maxHzSpinner.ValueChangedFcn = createCallbackFcn(app, @F_maxHzSpinnerValueChanged, true);
            app.F_maxHzSpinner.Value = 3;
            app.PQRSTButton = uibutton(app.MICAProjectUIFigure, 'push');
            app.PQRSTButton.ButtonPushedFcn = createCallbackFcn(app, @PQRSTButtonPushed, true);
            app.PQRSTButton.Text = 'PQRST';
            app.HeartrateButton = uibutton(app.MICAProjectUIFigure, 'push');
            app.HeartrateButton.ButtonPushedFcn = createCallbackFcn(app, @HeartrateButtonPushed, true);
            app.HeartrateButton.Text = 'Heart rate';
            app.AnalysisLabel = uilabel(app.MICAProjectUIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.TimeunitDropDownLabel = uilabel(app.MICAProjectUIFigure);
            app.TimeunitDropDownLabel.Text = 'Time unit';
            app.TimeunitDropDown = uidropdown(app.MICAProjectUIFigure);
            app.TimeunitDropDown.Items = {'sec', 'min'};
            app.TimeunitDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeunitDropDownValueChanged, true);
            app.TimeunitDropDown.Value = 'sec';
            app.TestsLabel = uilabel(app.MICAProjectUIFigure);
            app.TestsLabel.Text = 'Tests';
            app.EctopicButton = uibutton(app.MICAProjectUIFigure, 'push');
            app.EctopicButton.ButtonPushedFcn = createCallbackFcn(app, @EctopicButtonPushed, true);
            app.EctopicButton.Text = 'Ectopic';
            app.AtrialButton = uibutton(app.MICAProjectUIFigure, 'push');
            app.AtrialButton.ButtonPushedFcn = createCallbackFcn(app, @AtrialButtonPushed, true);
            app.AtrialButton.Text = 'Atrial';
            app.GeneralLabel = uilabel(app.MICAProjectUIFigure);
            app.GeneralLabel.Text = 'General';
            app.WindowsecSpinnerLabel = uilabel(app.MICAProjectUIFigure);
            app.WindowsecSpinnerLabel.Text = 'Window (sec)';
            app.WindowsecSpinner = uispinner(app.MICAProjectUIFigure);
            app.WindowsecSpinner.Limits = [1 90];
            app.WindowsecSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsecSpinnerValueChanged, true);
            app.WindowsecSpinner.Value = 30;
            app.WindowsecSpinner_2Label = uilabel(app.MICAProjectUIFigure);
            app.WindowsecSpinner_2Label.Text = 'Window (sec)';
            app.WindowsecSpinner_2 = uispinner(app.MICAProjectUIFigure);
            app.WindowsecSpinner_2.Limits = [1 90];
            app.WindowsecSpinner_2.ValueChangedFcn = createCallbackFcn(app, @WindowsecSpinner_2ValueChanged, true);
            app.WindowsecSpinner_2.Value = 30;
            app.QRStimemsEditFieldLabel = uilabel(app.MICAProjectUIFigure);
            app.QRStimemsEditFieldLabel.Text = 'QRS time (ms)';
            app.QRStimemsEditField = uieditfield(app.MICAProjectUIFigure, 'numeric');
            app.QRStimemsEditField.Limits = [5 200];
            app.QRStimemsEditField.RoundFractionalValues = 'on';
            app.QRStimemsEditField.ValueChangedFcn = createCallbackFcn(app, @QRStimemsEditFieldValueChanged, true);
            app.QRStimemsEditField.Value = 85;
            app.NfftSpinnerLabel = uilabel(app.MICAProjectUIFigure);
            app.NfftSpinnerLabel.Text = 'Nfft';
            app.NfftSpinner = uispinner(app.MICAProjectUIFigure);
            app.NfftSpinner.Step = 100;
            app.NfftSpinner.Limits = [128 16384];
            app.NfftSpinner.ValueChangedFcn = createCallbackFcn(app, @NfftSpinnerValueChanged, true);
            app.NfftSpinner.Value = 4096;
            app.UIAxes = uiaxes(app.MICAProjectUIFigure);
            title(app.UIAxes, 'Please load an ECG')
            % Show the figure after all components are created
            app.MICAProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MICA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MICAProjectUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MICAProjectUIFigure)
        end
    end
end